classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ResetButton        matlab.ui.control.Button
        StartbuttonButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    robotPos = [10, 50];    % Initial position of robot [x, y]
    robotAngle = 0;         % Direction (degrees)
    pathX                  % X data of the path
    pathY                  % Y data of the path
    robotPlot              % Handle for robot (red dot)
    timerObj               % Timer for animation
end
% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function startupFcn(app, event)
    
    % Define a curved path (sine wave)
    t = linspace(0, 10, 300);
    app.pathX = 10 * t;
    app.pathY = 50 + 30 * sin(t);

    % Plot the path
    plot(app.UIAxes, app.pathX, app.pathY, 'k--', 'LineWidth', 2);
    hold(app.UIAxes, 'on');
    axis(app.UIAxes, [0 120 0 100]);
    title(app.UIAxes, 'Line Following Robot');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');

    % Plot the robot
    app.robotPlot = plot(app.UIAxes, app.robotPos(1), app.robotPos(2), ...
        'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @startupFcn, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartbuttonButton = uibutton(app.UIFigure, 'push');
            app.StartbuttonButton.Text = 'Startbutton';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'ResetButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end